classdef gui_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResolutionSliderLabel     matlab.ui.control.Label
        ResolutionSlider          matlab.ui.control.Slider
        PreviewButton             matlab.ui.control.Button
        OrientationDropDownLabel  matlab.ui.control.Label
        OrientationDropDown       matlab.ui.control.DropDown
        UIAxes                    matlab.ui.control.UIAxes
        ToleranceSliderLabel      matlab.ui.control.Label
        ToleranceSlider           matlab.ui.control.Slider
        PrintButton               matlab.ui.control.Button
        UploadImageButton         matlab.ui.control.Button
        EtchASketchUploaderLabel  matlab.ui.control.Label
        CheckBox                  matlab.ui.control.CheckBox
        Label                     matlab.ui.control.Label
        Resnumber                 matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        Tolnumber                 matlab.ui.control.NumericEditField
        Team12RyanMaziarzJamesNellisandRuijiLiuLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            close all
            figure(app.UIFigure)
            resolution = app.ResolutionSlider.Value;
            orientation = app.OrientationDropDown.Value;
            tolerance = app.ToleranceSlider.Value;
            if app.CheckBox.Value == 1
                preview = ImageTranslator(app.image,resolution,orientation,tolerance);
                save('Pathcoordinates',"preview");
            end
            app.image = [];
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            app.image = [];
            [file,path] = uigetfile({'*.png;*.jpg','Select A File'});
            if path~=0
                cd(path);
                app.image = imread([path file]);
                app.CheckBox.Value = 1;
                app.CheckBox.Tooltip = {'Image successfully uploaded'; ''};
                imshow(app.image,'parent',app.UIAxes);
            end
            figure(app.UIFigure)
        end

        % Value changed function: ToleranceSlider
        function ToleranceSliderValueChanged(app, event)
            value = app.ToleranceSlider.Value;
            app.Tolnumber.Value = value;
        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            app.Resnumber.Value = value;
        end

        % Value changed function: Resnumber
        function ResnumberValueChanged(app, event)
            value = app.Resnumber.Value;
            app.ResolutionSlider.Value = value;
        end

        % Value changed function: Tolnumber
        function TolnumberValueChanged(app, event)
            value = app.Tolnumber.Value;
            app.ToleranceSlider.Value = value;
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            run("basecode.m")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.ResolutionSliderLabel = uilabel(app.UIFigure);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.UIFigure);
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.Tooltip = {'Control the number of pixels used'};
            app.ResolutionSlider.Value = 100;
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Tooltip = {'Preview the image prior to printing'};
            app.PreviewButton.Text = {'Preview'; ''};
            app.OrientationDropDownLabel = uilabel(app.UIFigure);
            app.OrientationDropDownLabel.Text = {'Orientation'; ''};
            app.OrientationDropDown = uidropdown(app.UIFigure);
            app.OrientationDropDown.Items = {'Down', 'Up', 'Left', 'Right'};
            app.OrientationDropDown.Tooltip = {'Control which direction the bottom of the image faces'};
            app.OrientationDropDown.Value = 'Down';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'User Image    ')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.ToleranceSliderLabel = uilabel(app.UIFigure);
            app.ToleranceSliderLabel.Text = 'Tolerance';
            app.ToleranceSlider = uislider(app.UIFigure);
            app.ToleranceSlider.Limits = [0 2];
            app.ToleranceSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.ToleranceSlider.ValueChangedFcn = createCallbackFcn(app, @ToleranceSliderValueChanged, true);
            app.ToleranceSlider.Tooltip = {'Control the contrast of the image'};
            app.ToleranceSlider.Value = 1;
            app.PrintButton = uibutton(app.UIFigure, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Tooltip = {'Print the image on the Etch-A-Sketch'};
            app.PrintButton.Text = {'Print'; ''};
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Tooltip = {'Upload an image to print'};
            app.UploadImageButton.Text = 'Upload Image';
            app.EtchASketchUploaderLabel = uilabel(app.UIFigure);
            app.EtchASketchUploaderLabel.Text = 'Etch-A-Sketch Uploader';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Tooltip = {'No image uploaded'};
            app.CheckBox.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Resnumber = uieditfield(app.UIFigure, 'numeric');
            app.Resnumber.Limits = [0 100];
            app.Resnumber.ValueDisplayFormat = '%.2f';
            app.Resnumber.ValueChangedFcn = createCallbackFcn(app, @ResnumberValueChanged, true);
            app.Resnumber.Value = 100;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Tolnumber = uieditfield(app.UIFigure, 'numeric');
            app.Tolnumber.Limits = [0 2];
            app.Tolnumber.ValueDisplayFormat = '%.2f';
            app.Tolnumber.ValueChangedFcn = createCallbackFcn(app, @TolnumberValueChanged, true);
            app.Tolnumber.Value = 1;
            app.Team12RyanMaziarzJamesNellisandRuijiLiuLabel = uilabel(app.UIFigure);
            app.Team12RyanMaziarzJamesNellisandRuijiLiuLabel.Text = {'Team 1.2 Ryan Maziarz, James Nellis, and Ruiji Liu'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end